classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        NLabel       matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        UITable      matlab.ui.control.Table
        Label_4      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        Button_4     matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Button
        function build(app, event)
            num=get(app.EditField,'Value');
            One=zeros(num,num+1);
            A=true(1,num+2);
            set(app.UITable,'Data',One,'ColumnEditable',A)
        end

        % Button pushed function: Button_2
        function solve(app, event)
             Matrix=get(app.UITable,'Data');
        num=get(app.EditField,'Value');
        B=Matrix(:,num+1);
        C=Matrix(:,1:end-1);
        x=C\B;
        set(app.EditField_2,'Value',mat2str(x))
        end

        % Button pushed function: Button_4
        function clear(app, event)
            set(app.UITable,'Data',{})
            set(app.EditField_2,'Value','   ')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N уравнений';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 15];
            app.EditField.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @build, true);
            app.Button.Text = 'Построить';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @solve, true);
            app.Button_2.Text = 'Решить';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Ответ:';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.Button_4.Text = 'Очистить';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end